classdef LPRAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        LicensePlateRecognitionStateIdentificaitonSystemsLabel  matlab.ui.control.Label
        DetectionPanel              matlab.ui.container.Panel
        PlateTypeLabel              matlab.ui.control.Label
        SeeDetailsButton            matlab.ui.control.Button
        StatePanel                  matlab.ui.container.Panel
        StateNameLabel              matlab.ui.control.Label
        StateImageAxes              matlab.ui.control.UIAxes
        CroppedPlateAxes            matlab.ui.control.UIAxes
        MainPanel                   matlab.ui.container.Panel
        SelectVehicleImageDropDown  matlab.ui.control.DropDown
        ImageDisplayAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ProcessingStepsImages % Description
    end
    
    methods (Access = private)

        function startDetection(app)
            % Get selected image from dropdown
            selectedImage = app.SelectVehicleImageDropDown.Value;
            
            % Placeholder: Load and display the selected image
            % Replace with actual image loading logic
            img = imread(selectedImage);
            imshow(img, 'Parent', app.ImageDisplayAxes);
            % Run simulated backend processing
            [croppedPlate, state, plateType, stepsImages] = app.detectionProcess(img);
            app.ProcessingStepsImages = stepsImages;
            
            % Display cropped plate
            imshow(croppedPlate, 'Parent', app.CroppedPlateAxes);
            
            % Update state display
            app.StateNameLabel.Text = sprintf('State: %s', state);
            % Load and display state flag

            flagPath = fullfile('flags', [state '.png']);
            disp(flagPath);
            try
                flagImg = imread(flagPath);
                imshow(flagImg, 'Parent', app.StateImageAxes);
            catch
                % Clear axes if flag image is missing
                cla(app.StateImageAxes);
                app.StateNameLabel.Text = sprintf('State: %s (Flag not found)', state);
            end

            app.PlateTypeLabel.Text = sprintf('Plate Type: %s', plateType);
        end
        
        function [croppedPlate, state, plateType, stepsImages] = detectionProcess(~, img)
            % Simulate image processing steps for license plate detection
            stepsImages = cell(4, 2); % Store images and descriptions

            % Step 1: Grayscale Conversion
            if size(img, 3) == 3
                grayImg = rgb2gray(img);
            else
                grayImg = img;
            end
            stepsImages{1, 1} = grayImg;
            stepsImages{1, 2} = 'Grayscale Conversion: Convert to grayscale for easier processing.';

            % Step 2: Edge Detection
            edgeImg = edge(grayImg, 'Canny');
            stepsImages{2, 1} = edgeImg;
            stepsImages{2, 2} = 'Edge Detection: Apply Canny edge detection to highlight plate boundaries.';

            % Step 3: Identify License Plate Region (Dummy Bounding Box)
            % Simulate finding a plate at a fixed location
            [height, width, ~] = size(img);
            bbox = [round(width*0.4), round(height*0.6), round(width*0.2), round(height*0.1)]; % [x, y, w, h]
            plateRegionImg = insertShape(img, 'Rectangle', bbox, 'LineWidth', 2, 'Color', 'red');
            stepsImages{3, 1} = plateRegionImg;
            stepsImages{3, 2} = 'Plate Detection: Identify license plate region with bounding box.';

            % Step 4: Crop License Plate
            croppedPlate = imcrop(img, bbox);
            stepsImages{4, 1} = croppedPlate;
            stepsImages{4, 2} = 'Plate Cropping: Extract the license plate region.';

            % Simulate state detection (first character mapping)
            stateMap = containers.Map(...
                {'J', 'K', 'D', 'M', 'N', 'P', 'G', 'R', 'L', 'S', 'W', 'T', 'B', 'Q'}, ...
                {'Johor', 'Kedah', 'Kelantan', 'Malacca', 'Negeri Sembilan', 'Pahang', ...
                 'Penang', 'Perak', 'Perlis', 'Sabah', 'Sarawak', 'Selangor', ...
                 'Terengganu', 'Kuala Lumpur'});
            % Dummy state detection
            stateChar = 'K'; % Example: Replace with actual OCR result
            if isKey(stateMap, stateChar)
                state = stateMap(stateChar);
            else
                state = 'Unknown';
            end
            plateType = 'Normal Vehicle';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes
            %app.ImageDisplayAxes.Visible = "off";
            %app.ImageDisplayAxes.Colormap = gray(256);
            %axis(app.ImageDisplayAxes,"image");

            %app.CroppedPlateAxes.Visible = "off";
            %app.CroppedPlateAxes.Colormap = gray(256);
            %axis(app.CroppedPlateAxes,"image");
            
            % Update the image and histograms
            %updateImage(app,"peppers.png");
            app.SelectVehicleImageDropDownValueChanged(struct('Source', app.SelectVehicleImageDropDown));
        end

        % Button pushed function: SeeDetailsButton
        function SeeDetailsButtonPushed(app, event)
               
           % Create a new figure to display image processing steps
            detailsFig = uifigure('Name', 'Image Processing Steps', ...
                'Position', [100, 100, 1000, 600], ...
                'Color', [0.95 0.95 0.95]);
            
            % Title
            uilabel(detailsFig, ...
                'Position', [20, 550, 960, 30], ...
                'Text', 'Image Processing Steps for License Plate Recognition & State Identification System', ...
                'FontSize', 16, ...
                'FontWeight', 'bold', ...
                'HorizontalAlignment', 'center', ...
                'BackgroundColor', [0.2 0.4 0.6], ...
                'FontColor', [1 1 1]);
            
            % Display each step with image and description
            for i = 1:size(app.ProcessingStepsImages, 1)
                % Create axes for image
                stepAxes = uiaxes(detailsFig, ...
                    'Position', [20, 450 - (i-1)*130, 200, 100], ...
                    'XTick', [], 'YTick', [], ...
                    'BackgroundColor', [1 1 1]);
                imshow(app.ProcessingStepsImages{i, 1}, 'Parent', stepAxes);
                title(stepAxes, sprintf('Step %d', i), 'FontSize', 12);
                
                % Create label for description
                uilabel(detailsFig, ...
                    'Position', [240, 450 - (i-1)*130, 740, 100], ...
                    'Text', app.ProcessingStepsImages{i, 2}, ...
                    'FontSize', 12, ...
                    'VerticalAlignment', 'top', ...
                    'WordWrap', 'on');
            end
        end

        % Value changed function: SelectVehicleImageDropDown
        function SelectVehicleImageDropDownValueChanged(app, event)
            startDetection(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'LPR & SIS';
            app.MainPanel = uipanel(app.UIFigure);
            app.MainPanel.Title = 'Vehicle Image Input';
            app.ImageDisplayAxes = uiaxes(app.MainPanel);
            title(app.ImageDisplayAxes, 'Original Image')
            app.ImageDisplayAxes.XTick = [];
            app.ImageDisplayAxes.XTickLabel = '';
            app.ImageDisplayAxes.YTick = [];
            app.SelectVehicleImageDropDown = uidropdown(app.MainPanel);
            app.SelectVehicleImageDropDown.Items = {'sms.jpg', 'ev.jpg', 'carplate.jpg', 'carplate.jpg'};
            app.SelectVehicleImageDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectVehicleImageDropDownValueChanged, true);
            app.SelectVehicleImageDropDown.Value = 'sms.jpg';
            app.DetectionPanel = uipanel(app.UIFigure);
            app.DetectionPanel.Title = 'Detection Results';
            app.CroppedPlateAxes = uiaxes(app.DetectionPanel);
            title(app.CroppedPlateAxes, 'Detected License Plate')
            app.CroppedPlateAxes.XTick = [];
            app.CroppedPlateAxes.XTickLabel = '';
            app.CroppedPlateAxes.YTick = [];
            app.StatePanel = uipanel(app.DetectionPanel);
            app.StatePanel.Title = 'Detected State';
            app.StateImageAxes = uiaxes(app.StatePanel);
            app.StateImageAxes.XTick = [];
            app.StateImageAxes.YTick = [];
            app.StateImageAxes.ZTick = [];
            app.StateNameLabel = uilabel(app.StatePanel);
            app.StateNameLabel.Text = 'State: None';
            app.SeeDetailsButton = uibutton(app.DetectionPanel, 'push');
            app.SeeDetailsButton.ButtonPushedFcn = createCallbackFcn(app, @SeeDetailsButtonPushed, true);
            app.SeeDetailsButton.Text = 'See Details';
            app.PlateTypeLabel = uilabel(app.DetectionPanel);
            app.PlateTypeLabel.Text = 'Plate Type: None';
            app.LicensePlateRecognitionStateIdentificaitonSystemsLabel = uilabel(app.UIFigure);
            app.LicensePlateRecognitionStateIdentificaitonSystemsLabel.Text = 'License Plate Recognition & State Identificaiton Systems';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LPRAPP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end